classdef Connexion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaceAppConnexionUIFigure     matlab.ui.Figure
        WelcometoFaceAppLabel        matlab.ui.control.Label
        LOGINButton                  matlab.ui.control.Button
        SIGNUPButton                 matlab.ui.control.Button
        NotregisteredyetSignupLabel  matlab.ui.control.Label
        usernameEditFieldLabel       matlab.ui.control.Label
        usernameEditField            matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        NoWarningMessageYetLabel     matlab.ui.control.Label
        FocusonthecameratogetregisteredLabel  matlab.ui.control.Label
        QUITButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        cam; % canera object
        videoFrame;  % image frame of the webcam
        
        vid % Video Object for snapshoots saves
        
        unregistered ; % checks when to stop the video
        userInterface;
        username;
        myDbPath='Database\';
        personIndex; % set of labels from the users in the database
        personLabel; % found label for the user that wants to acces the app
        bbox;
       
        
    end

    methods (Access = private)
    
        %------- Function to test if the image is RGB and convert it to Gray if true.  
        function results = isImageRGB(app,im)
            if size(im,3) == 3
            results = rgb2gray(im); 
            else
                results = im;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        %------------------------------------------------Load the classifier    
         faceGallery = imageSet(app.myDbPath, 'recursive');
        
         %% Create HoG training features from face gallery
        trainingFeatures = zeros(19,10404);
        featureCount = 1;
        trainingLabel{featureCount}=zeros();
       
        for i=1:size(faceGallery,2)
            for j = 1:faceGallery(i).Count
                
                %-------Test if the image is RGB
                sizeNormalizedImage = imresize(isImageRGB(app,(read(faceGallery(i),j)),[150 150]));
   
                trainingFeatures(featureCount,:) = extractHOGFeatures(sizeNormalizedImage);
                trainingLabel{featureCount} = faceGallery(i).Description;    
                featureCount = featureCount + 1;
            end
            app.personIndex{i} = faceGallery(i).Description;
        end
        
        %% Create Classifier 
        faceClassifier = fitcecoc(trainingFeatures,trainingLabel);

         
        %------------------------------------------------Preview the webcam
        app.vid = videoinput('winvideo',1,'MJPG_640x480');
        vidRes = app.vid.VideoResolution;
        hImage = image(app.UIAxes,zeros(vidRes));
        app.UIAxes.XLim = [0,vidRes(1)];
        app.UIAxes.YLim = [0,vidRes(2)];
        app.UIAxes.XTick = [];
        app.UIAxes.YTick = [];
        pbaspect(app.UIAxes,[vidRes(1),vidRes(2),1])
        preview(app.vid, hImage);
       
        %------------------------------------------------Start detection
        
        
         % Create the face detector object.
	faceDetector = vision.CascadeObjectDetector();

	% Create the point tracker object.
	pointTracker = vision.PointTracker('MaxBidirectionalError', 2);


	%%------------------------- Detection and Tracking
	% Capture and process video frames from the webcam in a loop to detect and
	% track a face. The loop will run until the user registratetes or quit
	

  		app.unregistered = true;
  		numPts = 0;
    
    %------Start Video Object		
    start(app.vid);
  	while  app.unregistered && isvalid(app.vid)


  		    % Get the next frame.
                    
                    %-----Take a snapshot of Video
  		    app.videoFrame = getsnapshot(app.vid);                   
  		    videoFrameGray = isImageRGB(app,(app.videoFrame));


                    %----------- Start tracking through the video
  		    
  		    if numPts < 10
  		        % Detection mode.
  		        app.bbox = faceDetector.step(videoFrameGray);
  		      
  		        if ~isempty(app.bbox)
                            
  		            % Find corner points inside the detected region.
  		            points = detectMinEigenFeatures(videoFrameGray, 'ROI', app.bbox(1, :));
  		            
  		            % Re-initialize the point tracker.
  		            xyPoints = points.Location;
  		            numPts = size(xyPoints,1);
  		            release(pointTracker);
  		            initialize(pointTracker, xyPoints, videoFrameGray);
  		            
  		            % Save a copy of the points.
  		            oldPoints = xyPoints;
  		            
  		            % Convert the rectangle represented as [x, y, w, h] into an
  		            % M-by-2 matrix of [x,y] coordinates of the four corners. This
  		            % is needed to be able to transform the bounding box to display
  		            % the orientation of the face.
  		            bboxPoints = bbox2points(app.bbox(1, :));
  		            
  		            % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
  		            % format required by insertShape.
  		            bboxPolygon = reshape(bboxPoints', 1, []);
  		            
  		            % Display a bounding box around the detected face.
  		            app.videoFrame = insertShape(app.videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
  		           
  		            % Display detected corners.
  		            app.videoFrame = insertMarker(app.videoFrame, xyPoints, '+', 'Color', 'white');
  		        end
      		        
     		     else
      		        % Tracking mode.
      		        [xyPoints, isFound] = step(pointTracker, videoFrameGray);
      		        visiblePoints = xyPoints(isFound, :);
      		        oldInliers = oldPoints(isFound, :);
                
      		        numPts = size(visiblePoints, 1);
      		        
      		        if numPts >= 10
      		            % Estimate the geometric transformation between the old points
      		            % and the new points.
      		            [xform, oldInliers, visiblePoints] = estimateGeometricTransform(...
      		                oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
      		            
      		            % Apply the transformation to the bounding box.
      		            bboxPoints = transformPointsForward(xform, bboxPoints);
      		            % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
      		            % format required by insertShape.
      		            bboxPolygon = reshape(bboxPoints', 1, []);   		            
      		            % Display a bounding box around the face being tracked.
      		            app.videoFrame = insertShape(app.videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);

      		            % Display tracked points.
      		            app.videoFrame = insertMarker(app.videoFrame, visiblePoints, 'o', 'Color', 'red');
      		            
      		            % Reset the points.
      		            oldPoints = visiblePoints;
      		            setPoints(pointTracker, oldPoints);
            		  end
                
  		    end


                    %----------- Recognize the detected face
                   
                    queryFeatures = extractHOGFeatures(isImageRGB(app,app.videoFrame));
                    %queryFeatures = extractHOGFeatures(isImageRGB(app,imresize(app.videoFrame,[150 150])));
                    %queryFeatures = extractHOGFeatures(imresize(app.videoFrame,[150 150]));
                    
                    app.personLabel = predict(faceClassifier,queryFeatures);
                    % Map back  to find identity
                    booleanIndex = strcmp(app.personLabel, app.personIndex);
                    integerIndex = find(booleanIndex);
                    
                    %----------- Decide 
                    
                    
  		    %----------- Display the annotated video frame
                    if isempty(app.username) 
                        app.videoFrame = insertObjectAnnotation(app.videoFrame,'rectangle',app.bbox,'RECOGNIZED');   
                        app.NoWarningMessageYetLabel.Text = ' Enter your username to get registered or signed in.';
                        app.NoWarningMessageYetLabel.FontColor=[1 0 0]; % Red warning message
                 
                    else
                         app.NoWarningMessageYetLabel.Text = ' ';
                    end
                    
                    preview(app.vid,image(app.UIAxes,app.videoFrame));
                   
                    if ~app.unregistered
                        
                                % Clean up.
          		release(pointTracker);
          		release(faceDetector);
                        closePreview(app.cam); 
                        break;
                    end
    
            
  	end % end while
    stop(app.vid);
    %-----Stop Video Object
  		
        
        end

        % Button pushed function: LOGINButton
        function loginPushed(app, event)
            if ~isempty(app.username) 
               if ~strcmp(app.personLabel, app.username)                
                    app.NoWarningMessageYetLabel.Text = 'Mismatch. Wrong username!'; 
                    app.NoWarningMessageYetLabel.FontColor=[1 0 0]; % Red warning message
             
               else
                   app.NoWarningMessageYetLabel.Text = 'Great, welcome back!'; 
                   app.videoFrame = insertObjectAnnotation(app.videoFrame,'rectangle',app.bbox,app.personLabel);   
                % Decide
                app.unregistered = false;
                app.userInterface = UserInterface(app,app.username);
                delete(app);
               end % end comparison
            else
                app.NoWarningMessageYetLabel.Text = ' First enter your username!';
                app.NoWarningMessageYetLabel.FontColor=[1 0 0]; % Red warning message
             
            end % end verifying username
             
        end

        % Button pushed function: SIGNUPButton
        function signInPushed(app, event)
            if ~isempty(app.username) 
                if strcmp(app.personLabel,app.username)                
                    app.NoWarningMessageYetLabel.Text = 'You are already in the database!'; 
                    app.NoWarningMessageYetLabel.FontColor=[1 0 0]; % Red warning message
             
               else
                   app.unregistered=false;
                   
                                 
                    %app.videoFrame = image(app.UIAxes); 
                    %app.cam.Resolution='640x480';
                    %app.UIAxes.XLim =[0,640];
                    %app.UIAxes.YLim =[0,480];
                    %axis(app.UIAxes,'ij');
                    %preview(app.cam, app.videoFrame);
                    
                 
                    % --Fill the new user database with 10 snapshots 
                    app.NoWarningMessageYetLabel.Text = ' Your database is being filled with 10 snaps. Please, take good poses.';
                    app.NoWarningMessageYetLabel.FontColor=[0 1 0]; % Red warning message
             
                    mkdir(app.myDbPath,app.username);
                    userDbFolder=strcat(app.myDbPath,app.username,'\');
                   
                    %---------Tigger for snap registering in DB
                    triggerconfig(app.vid, 'manual');
                    app.vid.FramesPerTrigger = inf; 
                    
                    start(app.vid);
                    trigger(app.vid) 
                    
                    for i=1:10
                    
                        inputSnap = getdata(app.vid,1);                            
                           
                        % detecting faces
                        facedetector = vision.CascadeObjectDetector();
                        b_boxes = facedetector(inputSnap); 
                            
                        % Resizing image
                        cropedIm =imcrop(inputSnap,b_boxes);    
                        grayImage=rgb2gray(cropedIm);
                        %grayImage=cropedIm;
                        resizedIm=imresize(grayImage,[150 150]);
                            
                        %Register the image
                        imagePathToDb=strcat(userDbFolder,num2str(i),'.jpeg');
                        imwrite(resizedIm,imagePathToDb);
                    end % end of filling database
                   
                    stop(app.vid);
                    closepreview(app.vid);
                    %-------En of Trigger
                end
                %--------------
                
                app.NoWarningMessageYetLabel.Text = strcat('Welcome to FaceApp,',app.username,'!');
                app.userInterface = UserInterface(app,app.username);
                delete(app);
            else
                app.NoWarningMessageYetLabel.Text = ' First enter your username!';
                app.NoWarningMessageYetLabel.FontColor=[1 0 0]; % Red warning message
             
            end
          
        end

        % Close request function: FaceAppConnexionUIFigure
        function FaceAppConnexionUIFigureCloseRequest(app, event)
             delete(app.userInterface);
             delete(app);
        end

        % Value changed function: usernameEditField
        function usernameValueChanged(app, event)
            if ~isempty(app.usernameEditField.Value)
                value = app.usernameEditField.Value;
                app.username = value;
            end
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            delete(app.cam);
            delete(app.vid);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaceAppConnexionUIFigure = uifigure;
            app.FaceAppConnexionUIFigure.Name = 'FaceApp - Connexion';
            app.FaceAppConnexionUIFigure.CloseRequestFcn = createCallbackFcn(app, @FaceAppConnexionUIFigureCloseRequest, true);
            app.WelcometoFaceAppLabel = uilabel(app.FaceAppConnexionUIFigure);
            app.WelcometoFaceAppLabel.Text = 'Welcome to FaceApp!';
            app.LOGINButton = uibutton(app.FaceAppConnexionUIFigure, 'push');
            app.LOGINButton.ButtonPushedFcn = createCallbackFcn(app, @loginPushed, true);
            app.LOGINButton.Text = 'LOG IN';
            app.SIGNUPButton = uibutton(app.FaceAppConnexionUIFigure, 'push');
            app.SIGNUPButton.ButtonPushedFcn = createCallbackFcn(app, @signInPushed, true);
            app.SIGNUPButton.Text = 'SIGN UP';
            app.NotregisteredyetSignupLabel = uilabel(app.FaceAppConnexionUIFigure);
            app.NotregisteredyetSignupLabel.Text = ' Not registered yet? Sign up.';
            app.usernameEditFieldLabel = uilabel(app.FaceAppConnexionUIFigure);
            app.usernameEditFieldLabel.Text = 'username';
            app.usernameEditField = uieditfield(app.FaceAppConnexionUIFigure, 'text');
            app.usernameEditField.ValueChangedFcn = createCallbackFcn(app, @usernameValueChanged, true);
            app.UIAxes = uiaxes(app.FaceAppConnexionUIFigure);
            title(app.UIAxes, 'FaceCam')
            app.NoWarningMessageYetLabel = uilabel(app.FaceAppConnexionUIFigure);
            app.NoWarningMessageYetLabel.Text = 'No Warning Message Yet';
            app.FocusonthecameratogetregisteredLabel = uilabel(app.FaceAppConnexionUIFigure);
            app.FocusonthecameratogetregisteredLabel.Text = 'Focus on the camera to get registered.';
            app.QUITButton = uibutton(app.FaceAppConnexionUIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Connexion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaceAppConnexionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaceAppConnexionUIFigure)
        end
    end
end