classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaceAppGalleryUIFigure  matlab.ui.Figure
        usernamePanel           matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        FiltersButtonGroup      matlab.ui.container.ButtonGroup
        ContrastButton          matlab.ui.control.Button
        MedianButton            matlab.ui.control.StateButton
        GaussianButton          matlab.ui.control.StateButton
        RotationButtonGroup     matlab.ui.container.ButtonGroup
        LeftButton              matlab.ui.control.StateButton
        RightButton             matlab.ui.control.StateButton
        ButtonGroup             matlab.ui.container.ButtonGroup
        SaveButton              matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        LoadphotoButton         matlab.ui.control.Button
    end


    properties (Access = private)
        connexion; % FaceApp connexion window
        username; % name of connected user
        chosenImage; % image loaded by the user
        modifiedImage; % Modified Image
        angle; % Angle value for rotation 
        filter = {'*.jpeg';'*.jpg';'*.png';'*.bmp';'*.gif';'*.tiff'};
        logo = imread('FaceAppLogo.jpg');
    end

    methods (Access = private)
    
        function calculAngleRight(app)
            if app.angle == 360
               app.angle = 0 ; 
            else
                app.angle =+ 90; 
            end
            
        end
        
        function calculAngleLeft(app)
            if app.angle == 0
                app.angle = 360; 
            else
                app.angle =- 90;
            end
            
        end
        
        function updatePreview(app)
            imshow(app.modifiedImage, 'parent', app.UIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConnexionInterface, user)

              %app.connexion = ConnexionInterface;
              %app.username = user;
              %app.usernamePanel.Title =user;
             app.angle=0; 
             app.UIAxes.XLim =[0,640];
             app.UIAxes.YLim =[0,480];
             axis(app.UIAxes,'image');
             imshow(app.logo, 'parent', app.UIAxes);
        end

        % Button pushed function: LoadphotoButton
        function loadPhotoPushed(app, event)
             [file,path]=uigetfile(app.filter, 'Select an image.');
              fichier = [path,file];
             app.chosenImage = imread(fichier);
             imshow(app.chosenImage, 'parent', app.UIAxes);
             app.modifiedImage = app.chosenImage;
        end

        % Button pushed function: SaveButton
        function saveButtonChanged(app, event)
             [file,folder] = uiputfile(app.filter, 'Select where to save your image');
             
             if file == 0
                % User clicked the Cancel button.
                return;
             end
        fullFileName = fullfile(folder, file);
        imwrite(app.modifiedImage, fullFileName);
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
           app.modifiedImage = app.chosenImage;
           updatePreview(app);
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
            %tempIm = rgb2gray(app.chosenImage);
            app.modifiedImage = histeq(app.chosenImage);
            updatePreview(app);
        end

        % Value changed function: MedianButton
        function MedianButtonPushedChanged(app, event)
            tempIm = rgb2gray(app.chosenImage);
            J = imnoise(tempIm,'poisson');
            app.modifiedImage = medfilt2(J);
            updatePreview(app);
        end

        % Value changed function: GaussianButton
        function GaussianButonPushed(app, event)
            app.modifiedImage = imgaussfilt(app.chosenImage,2);
            updatePreview(app);          
        end

        % Value changed function: RightButton
        function RightButtonPushed(app, event)
            calculAngleRight(app);
            app.modifiedImage = imrotate(app.modifiedImage,app.angle,'bicubic','crop');
            updatePreview(app);
        end

        % Value changed function: LeftButton
        function LeftButtonPushed(app, event)
            calculAngleLeft(app);
            app.modifiedImage = imrotate(app.modifiedImage,app.angle,'bicubic','crop');
            updatePreview(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaceAppGalleryUIFigure = uifigure;
            app.FaceAppGalleryUIFigure.Name = 'FaceApp - Gallery';
            app.usernamePanel = uipanel(app.FaceAppGalleryUIFigure);
            app.usernamePanel.Title = 'username';
            app.UIAxes = uiaxes(app.usernamePanel);
            title(app.UIAxes, 'Enjoy FaceApp!')
            app.FiltersButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.FiltersButtonGroup.Title = 'Filters';
            app.ContrastButton = uibutton(app.FiltersButtonGroup, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.MedianButton = uibutton(app.FiltersButtonGroup, 'state');
            app.MedianButton.ValueChangedFcn = createCallbackFcn(app, @MedianButtonPushedChanged, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.FiltersButtonGroup, 'state');
            app.GaussianButton.ValueChangedFcn = createCallbackFcn(app, @GaussianButonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.RotationButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.RotationButtonGroup.Title = 'Rotation';
            app.LeftButton = uibutton(app.RotationButtonGroup, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.RotationButtonGroup, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonChanged, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.ButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadphotoButton = uibutton(app.ButtonGroup, 'push');
            app.LoadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @loadPhotoPushed, true);
            app.LoadphotoButton.Text = 'Load photo';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UserInterface(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaceAppGalleryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaceAppGalleryUIFigure)
        end
    end
end