classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FaceAppGalleryUIFigure     matlab.ui.Figure
        usernamePanel              matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        FilterimageButtonGroup     matlab.ui.container.ButtonGroup
        ContrastButton             matlab.ui.control.Button
        MedianButton               matlab.ui.control.StateButton
        GaussianButton             matlab.ui.control.StateButton
        RotateimageButtonGroup     matlab.ui.container.ButtonGroup
        LeftButton                 matlab.ui.control.StateButton
        RightButton                matlab.ui.control.StateButton
        ActionsonimageButtonGroup  matlab.ui.container.ButtonGroup
        SaveButton                 matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        connexion; % FaceApp connexion window
        username; % name of connected user
        chosenImage; % image loaded by the user
        modifiedImage; % Modified Image
        angle; % Angle value for rotation 
        filter = {'*.jpeg';'*.jpg';'*.png';'*.bmp';'*.gif';'*.tiff'};
        logo = imread('.\assets\FaceAppLogo.jpg');
    end

    methods (Access = private)
    
  
        function updatePreview(app)
            imshow(app.modifiedImage, 'parent', app.UIAxes);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ConnexionInterface, user)

             app.connexion = ConnexionInterface;
             app.username = user;
             app.usernamePanel.Title =user;
             app.angle=0; 
             app.UIAxes.XLim =[0,640];
             app.UIAxes.YLim =[0,480];
             axis(app.UIAxes,'image');
             imshow(app.logo, 'parent', app.UIAxes);
        end

        % Button pushed function: LoadButton
        function loadPhotoPushed(app, event)
             [file,path]=uigetfile(app.filter, 'Select an image.');
              fichier = [path,file];
              if file == 0
                  return;
              else
                   app.chosenImage = imread(fichier);   
                   imshow(app.chosenImage, 'parent', app.UIAxes);
                   app.modifiedImage = app.chosenImage;
              end
        end

        % Button pushed function: SaveButton
        function saveButtonChanged(app, event)
             [file,folder] = uiputfile(app.filter, 'Select where to save your image');
             
             if file == 0
                % User clicked the Cancel button.
                return;
             end
        fullFileName = fullfile(folder, file);
        imwrite(app.modifiedImage, fullFileName);
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
           app.modifiedImage = app.chosenImage;
           updatePreview(app);
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
            %tempIm = rgb2gray(app.chosenImage);
            app.modifiedImage = histeq(app.chosenImage);
            updatePreview(app);
        end

        % Value changed function: MedianButton
        function MedianButtonPushedChanged(app, event)
            tempIm = rgb2gray(app.chosenImage);
            J = imnoise(tempIm,'poisson');
            app.modifiedImage = medfilt2(J);
            updatePreview(app);
        end

        % Value changed function: GaussianButton
        function GaussianButonPushed(app, event)
            app.modifiedImage = imgaussfilt(app.chosenImage,2);
            updatePreview(app);          
        end

        % Value changed function: RightButton
        function RightButtonPushed(app, event)
            
            app.modifiedImage = imrotate(app.modifiedImage,90,'bicubic','crop');
            updatePreview(app);
        end

        % Value changed function: LeftButton
        function LeftButtonPushed(app, event)
            
            app.modifiedImage = imrotate(app.modifiedImage,-90,'bicubic','crop');
            updatePreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FaceAppGalleryUIFigure = uifigure('Visible', 'off');
            app.FaceAppGalleryUIFigure.Name = 'FaceApp - Gallery';
            app.usernamePanel = uipanel(app.FaceAppGalleryUIFigure);
            app.usernamePanel.Title = 'username';
            app.UIAxes = uiaxes(app.usernamePanel);
            title(app.UIAxes, 'Enjoy FaceApp!')
            app.FilterimageButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.FilterimageButtonGroup.Title = 'Filter image';
            app.ContrastButton = uibutton(app.FilterimageButtonGroup, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.MedianButton = uibutton(app.FilterimageButtonGroup, 'state');
            app.MedianButton.ValueChangedFcn = createCallbackFcn(app, @MedianButtonPushedChanged, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.FilterimageButtonGroup, 'state');
            app.GaussianButton.ValueChangedFcn = createCallbackFcn(app, @GaussianButonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.RotateimageButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.RotateimageButtonGroup.Title = 'Rotate image';
            app.LeftButton = uibutton(app.RotateimageButtonGroup, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.RotateimageButtonGroup, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ActionsonimageButtonGroup = uibuttongroup(app.FaceAppGalleryUIFigure);
            app.ActionsonimageButtonGroup.Title = 'Actions on image';
            app.SaveButton = uibutton(app.ActionsonimageButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonChanged, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.ActionsonimageButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadButton = uibutton(app.ActionsonimageButtonGroup, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadPhotoPushed, true);
            app.LoadButton.Text = 'Load ';
            % Show the figure after all components are created
            app.FaceAppGalleryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FaceAppGalleryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FaceAppGalleryUIFigure)
        end
    end
end